classdef main_board < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HAWKStudienarbeitLeafdiseasesclassification2021UIFigure  matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        Image                        matlab.ui.control.Image
        LoadimageButton              matlab.ui.control.Button
        ClassifyButton               matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
        ClassissupposedtobeDropDownLabel  matlab.ui.control.Label
        ClassissupposedtobeDropDown  matlab.ui.control.DropDown
        Lamp                         matlab.ui.control.Lamp
    end

    
    properties (Access = public)
        I        % Image variable
        hawk     %learning_network
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ClassifyButton.Enable='off';
            load('hawk_neu_5_04_completeset.mat');
            app.hawk=hawk;
            app.Lamp.Color=[0.8, 0.8, 0.8]; 
            app.UITable.Data=[];
        end

        % Button pushed function: LoadimageButton
        function LoadimageButtonPushed(app, event)
            [file,path] = uigetfile({'*.jpg';'*.png'},'Please select an image');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                disp(['User selected ', fullfile(path,file)]);
                
                try
                    app.UITable.Data=[];
                    app.I=imread(fullfile(path,file));
                    app.Image.ImageSource=fullfile(path,file);
                    app.ClassifyButton.Enable='on';
                    app.Lamp.Color=[0.8, 0.8, 0.8]; 
                    
                catch ME
                    app.ClassifyButton.Enable='off';
                    f = msgbox('Image load failed, please repeat!');
                end
                
                
            end
        end

        % Button pushed function: ClassifyButton
        function ClassifyButtonPushed(app, event)
            hawk=app.hawk;
            I=app.I;
            I=imresize(I,[224 224]);
            [label,scores] = classify(hawk,I);
            label=string(label);
             f = msgbox("The class is : "+label);
            app.UITable.Data=scores;
            if(strcmp(upper(label),upper(app.ClassissupposedtobeDropDown.Value)))
               app.Lamp.Color=[0.286, 1, 0.121]; 
            else
               app.Lamp.Color=[0.952, 0.070, 0.098];  
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HAWKStudienarbeitLeafdiseasesclassification2021UIFigure = uifigure('Visible', 'off');
            app.HAWKStudienarbeitLeafdiseasesclassification2021UIFigure.Name = 'HAWK || Studienarbeit : Leaf diseases classification || 2021';
            app.GridLayout = uigridlayout(app.HAWKStudienarbeitLeafdiseasesclassification2021UIFigure);
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = 'logo.jpg';
            app.LoadimageButton = uibutton(app.GridLayout, 'push');
            app.LoadimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadimageButtonPushed, true);
            app.LoadimageButton.Text = 'Load image';
            app.ClassifyButton = uibutton(app.GridLayout, 'push');
            app.ClassifyButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyButtonPushed, true);
            app.ClassifyButton.Text = 'Classify';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Healthy'; 'red_spider_mite'; 'rust_level_1'; 'rust_level_2'; 'rust_level_3'; 'rust_level_4'};
            app.UITable.RowName = {};
            app.ClassissupposedtobeDropDownLabel = uilabel(app.GridLayout);
            app.ClassissupposedtobeDropDownLabel.Text = 'Class is supposed to be';
            app.ClassissupposedtobeDropDown = uidropdown(app.GridLayout);
            app.ClassissupposedtobeDropDown.Items = {'Healthy', 'red_spider_mite', 'rust_level_1', 'rust_level_2', 'rust_level_3', 'rust_level_4'};
            app.ClassissupposedtobeDropDown.Value = 'Healthy';
            app.Lamp = uilamp(app.GridLayout);
            % Show the figure after all components are created
            app.HAWKStudienarbeitLeafdiseasesclassification2021UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_board

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HAWKStudienarbeitLeafdiseasesclassification2021UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HAWKStudienarbeitLeafdiseasesclassification2021UIFigure)
        end
    end
end